classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Ventana2c                  matlab.ui.Figure
        decoloresadecuadaparadicharesistenciaPanel  matlab.ui.container.Panel
        PrimerabandaButtonGroup_3  matlab.ui.container.ButtonGroup
        VioletaButton              matlab.ui.control.RadioButton
        NegroButton                matlab.ui.control.RadioButton
        RojoButton_2               matlab.ui.control.RadioButton
        PrimerabandaButtonGroup_2  matlab.ui.container.ButtonGroup
        AmarilloButton             matlab.ui.control.RadioButton
        GrisButton                 matlab.ui.control.RadioButton
        NaranjaButton              matlab.ui.control.RadioButton
        PrimerabandaButtonGroup    matlab.ui.container.ButtonGroup
        AzulButton                 matlab.ui.control.RadioButton
        VerdeButton                matlab.ui.control.RadioButton
        RojoButton                 matlab.ui.control.RadioButton
        CuleslasecuenciadecoloresadecuadaparadicharesistenciaLabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        VolverButton               matlab.ui.control.Button
        ComprobarButton            matlab.ui.control.Button
        ElectroChallengeLabel      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app5
        end

        % Callback function: not associated with a component
        function PrimerabandaButtonGroupSelectionChanged(app, event)
            selectedButton = app.PrimerabandaButtonGroup.SelectedObject
        end

        % Button pushed function: ComprobarButton
        function ComprobarButtonPushed(app, event)
            global r2 s1 t1 sum1;
            sum1 = r2 + s1 + t1;
            if sum1 == 3
                app7;
            elseif sum1 == 2
                disp('Solo acomulo dos puntos');
                app7;
            else
                disp('Por favor intentelo de nuevo')
            end 
        end

        % Selection changed function: PrimerabandaButtonGroup
        function PrimerabandaButtonGroupSelectionChanged2(app, event)
            global r1 r2 r3
            selectedButton = app.PrimerabandaButtonGroup.SelectedObject;
            r1=app.RojoButton.Value;
            r2=app.VerdeButton.Value;
            r3=app.AzulButton.Value;
        end

        % Selection changed function: PrimerabandaButtonGroup_2
        function PrimerabandaButtonGroup_2SelectionChanged(app, event)
            global s1 s2 s3
            selectedButton = app.PrimerabandaButtonGroup_2.SelectedObject;
            s1=app.NaranjaButton.Value;
            s2=app.GrisButton.Value;
            s3=app.AmarilloButton.Value;
        end

        % Selection changed function: PrimerabandaButtonGroup_3
        function PrimerabandaButtonGroup_3SelectionChanged(app, event)
            global t1 t2 t3
            selectedButton = app.PrimerabandaButtonGroup_3.SelectedObject;
            t1=app.RojoButton.Value;
            t2=app.NegroButton.Value;
            t3=app.VioletaButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Ventana2c = uifigure('Visible', 'off');
            app.Ventana2c.Name = 'MATLAB App';
            app.Label = uilabel(app.Ventana2c);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Ventana2c);
            app.Label_2.Text = '';
            app.ElectroChallengeLabel = uilabel(app.Ventana2c);
            app.ElectroChallengeLabel.Text = 'Electro Challenge';
            app.ComprobarButton = uibutton(app.Ventana2c, 'push');
            app.ComprobarButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed, true);
            app.ComprobarButton.Text = 'Comprobar';
            app.VolverButton = uibutton(app.Ventana2c, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '<<   Volver';
            app.Image = uiimage(app.Ventana2c);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Ohmnios_2.gif');
            app.Image2 = uiimage(app.Ventana2c);
            app.Image2.ImageSource = 'Desf-.png';
            app.decoloresadecuadaparadicharesistenciaPanel = uipanel(app.Ventana2c);
            app.decoloresadecuadaparadicharesistenciaPanel.Title = 'Si se desea conseguir una resistencia de 5.3k ohm.';
            app.CuleslasecuenciadecoloresadecuadaparadicharesistenciaLabel = uilabel(app.decoloresadecuadaparadicharesistenciaPanel);
            app.CuleslasecuenciadecoloresadecuadaparadicharesistenciaLabel.Text = {' ¿Cuál es la secuencia de colores adecuada'; ' para dicha resistencia?'};
            app.PrimerabandaButtonGroup = uibuttongroup(app.decoloresadecuadaparadicharesistenciaPanel);
            app.PrimerabandaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PrimerabandaButtonGroupSelectionChanged2, true);
            app.PrimerabandaButtonGroup.Title = 'Primera banda';
            app.RojoButton = uiradiobutton(app.PrimerabandaButtonGroup);
            app.RojoButton.Text = 'Rojo';
            app.RojoButton.Value = true;
            app.VerdeButton = uiradiobutton(app.PrimerabandaButtonGroup);
            app.VerdeButton.Text = 'Verde';
            app.AzulButton = uiradiobutton(app.PrimerabandaButtonGroup);
            app.AzulButton.Text = 'Azul';
            app.PrimerabandaButtonGroup_2 = uibuttongroup(app.decoloresadecuadaparadicharesistenciaPanel);
            app.PrimerabandaButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @PrimerabandaButtonGroup_2SelectionChanged, true);
            app.PrimerabandaButtonGroup_2.Title = 'Primera banda';
            app.NaranjaButton = uiradiobutton(app.PrimerabandaButtonGroup_2);
            app.NaranjaButton.Text = 'Naranja';
            app.NaranjaButton.Value = true;
            app.GrisButton = uiradiobutton(app.PrimerabandaButtonGroup_2);
            app.GrisButton.Text = 'Gris';
            app.AmarilloButton = uiradiobutton(app.PrimerabandaButtonGroup_2);
            app.AmarilloButton.Text = 'Amarillo';
            app.PrimerabandaButtonGroup_3 = uibuttongroup(app.decoloresadecuadaparadicharesistenciaPanel);
            app.PrimerabandaButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @PrimerabandaButtonGroup_3SelectionChanged, true);
            app.PrimerabandaButtonGroup_3.Title = 'Primera banda';
            app.RojoButton_2 = uiradiobutton(app.PrimerabandaButtonGroup_3);
            app.RojoButton_2.Text = 'Rojo';
            app.RojoButton_2.Value = true;
            app.NegroButton = uiradiobutton(app.PrimerabandaButtonGroup_3);
            app.NegroButton.Text = 'Negro';
            app.VioletaButton = uiradiobutton(app.PrimerabandaButtonGroup_3);
            app.VioletaButton.Text = 'Violeta';
            % Show the figure after all components are created
            app.Ventana2c.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Ventana2c)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Ventana2c)
        end
    end
end