classdef app8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Ventana2e              matlab.ui.Figure
        SeleccionelacategoriaalacualpertenecenloscircuitosPanel  matlab.ui.container.Panel
        CircuitoBButtonGroup   matlab.ui.container.ButtonGroup
        ParaleloButton_2       matlab.ui.control.RadioButton
        SerieButton_2          matlab.ui.control.RadioButton
        CircuitoAButtonGroup   matlab.ui.container.ButtonGroup
        ParaleloButton         matlab.ui.control.RadioButton
        SerieButton            matlab.ui.control.RadioButton
        BLabel                 matlab.ui.control.Label
        ALabel                 matlab.ui.control.Label
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        VolverButton           matlab.ui.control.Button
        ComprobarButton        matlab.ui.control.Button
        ElectroChallengeLabel  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: CircuitoAButtonGroup
        function CircuitoAButtonGroupSelectionChanged(app, event)
            global se1 se2 
            selectedButton = app.CircuitoAButtonGroup.SelectedObject;
            se1=app.SerieButton.Value
            se2=app.ParaleloButton.Value
            
        end

        % Button pushed function: ComprobarButton
        function ComprobarButtonPushed(app, event)
            global se2 ci1;
            sum1 = se2 + ci1;
            if sum1 == 2
                app9;
            else
                disp('Por favor intentelo de nuevo')
            end 
        end

        % Selection changed function: CircuitoBButtonGroup
        function CircuitoBButtonGroupSelectionChanged(app, event)
            global ci1 ci2 
            selectedButton = app.CircuitoBButtonGroup.SelectedObject;
            ci1=app.SerieButton.Value
            ci2=app.ParaleloButton.Value
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app7
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Ventana2e = uifigure('Visible', 'off');
            app.Ventana2e.Name = 'MATLAB App';
            app.Label = uilabel(app.Ventana2e);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Ventana2e);
            app.Label_2.Text = '';
            app.ElectroChallengeLabel = uilabel(app.Ventana2e);
            app.ElectroChallengeLabel.Text = 'Electro Challenge';
            app.ComprobarButton = uibutton(app.Ventana2e, 'push');
            app.ComprobarButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed, true);
            app.ComprobarButton.Text = 'Comprobar';
            app.VolverButton = uibutton(app.Ventana2e, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '<<   Volver';
            app.Image = uiimage(app.Ventana2e);
            app.Image.ImageSource = 'circu_para.jpeg';
            app.Image2 = uiimage(app.Ventana2e);
            app.Image2.ImageSource = 'Circu_serie.jpeg';
            app.ALabel = uilabel(app.Ventana2e);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.Ventana2e);
            app.BLabel.Text = 'B';
            app.SeleccionelacategoriaalacualpertenecenloscircuitosPanel = uipanel(app.Ventana2e);
            app.SeleccionelacategoriaalacualpertenecenloscircuitosPanel.Title = 'Seleccione la categoria a la cual pertenecen los circuitos:';
            app.CircuitoAButtonGroup = uibuttongroup(app.SeleccionelacategoriaalacualpertenecenloscircuitosPanel);
            app.CircuitoAButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CircuitoAButtonGroupSelectionChanged, true);
            app.CircuitoAButtonGroup.Title = 'Circuito A';
            app.SerieButton = uiradiobutton(app.CircuitoAButtonGroup);
            app.SerieButton.Text = 'Serie';
            app.SerieButton.Value = true;
            app.ParaleloButton = uiradiobutton(app.CircuitoAButtonGroup);
            app.ParaleloButton.Text = 'Paralelo';
            app.CircuitoBButtonGroup = uibuttongroup(app.SeleccionelacategoriaalacualpertenecenloscircuitosPanel);
            app.CircuitoBButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CircuitoBButtonGroupSelectionChanged, true);
            app.CircuitoBButtonGroup.Title = 'Circuito B';
            app.SerieButton_2 = uiradiobutton(app.CircuitoBButtonGroup);
            app.SerieButton_2.Text = 'Serie';
            app.SerieButton_2.Value = true;
            app.ParaleloButton_2 = uiradiobutton(app.CircuitoBButtonGroup);
            app.ParaleloButton_2.Text = 'Paralelo';
            % Show the figure after all components are created
            app.Ventana2e.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Ventana2e)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Ventana2e)
        end
    end
end